classdef PRMIA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PortfolioSelectionTab           matlab.ui.container.Tab
        AddOptiontoPortfolioPanel       matlab.ui.container.Panel
        SetDateandRatesButton           matlab.ui.control.Button
        AddtoPortfolioButton            matlab.ui.control.Button
        DeletePortfolioButton           matlab.ui.control.Button
        OptionStyleButtonGroup          matlab.ui.container.ButtonGroup
        EuropeanButton                  matlab.ui.control.RadioButton
        AmericanButton                  matlab.ui.control.RadioButton
        PositionButtonGroup             matlab.ui.container.ButtonGroup
        LongButton                      matlab.ui.control.RadioButton
        ShortButton                     matlab.ui.control.RadioButton
        OptionTypeButtonGroup           matlab.ui.container.ButtonGroup
        CallButton                      matlab.ui.control.RadioButton
        PutButton                       matlab.ui.control.RadioButton
        InstrumentTypeDropDown          matlab.ui.control.DropDown
        InstrumentTypeDropDownLabel     matlab.ui.control.Label
        StrikeEditField                 matlab.ui.control.NumericEditField
        StrikeEditFieldLabel            matlab.ui.control.Label
        UnderlyingPriceEditFieldLabel   matlab.ui.control.Label
        UnderlyingPriceEditField        matlab.ui.control.NumericEditField
        VolatilityEditFieldLabel        matlab.ui.control.Label
        VolatilityEditField             matlab.ui.control.NumericEditField
        OptionMaturityDatePickerLabel   matlab.ui.control.Label
        OptionMaturityDatePicker        matlab.ui.control.DatePicker
        YieldEditField                  matlab.ui.control.NumericEditField
        YieldLabel                      matlab.ui.control.Label
        BarrierPanel                    matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        BarrierTypeDropDown             matlab.ui.control.DropDown
        BarrierTypeLabel                matlab.ui.control.Label
        BarrierValueLabel               matlab.ui.control.Label
        BarrierValueEditField           matlab.ui.control.NumericEditField
        BinaryPanel                     matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        BinaryValueEditField            matlab.ui.control.NumericEditField
        BinaryValueEditFieldLabel       matlab.ui.control.Label
        SimulationTrialsEditFieldLabel  matlab.ui.control.Label
        SimulationTrialsEditField       matlab.ui.control.NumericEditField
        QuantitySpinner                 matlab.ui.control.Spinner
        QuantitySpinnerLabel            matlab.ui.control.Label
        BaseCurrencyDropDownLabel       matlab.ui.control.Label
        BaseCurrencyDropDown            matlab.ui.control.DropDown
        PortfolioCompositionPanel       matlab.ui.container.Panel
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UITable3                        matlab.ui.control.Table
        UITable4                        matlab.ui.control.Table
        UITable2                        matlab.ui.control.Table
        LONGLabel                       matlab.ui.control.Label
        SHORTLabel                      matlab.ui.control.Label
        TOTLabel                        matlab.ui.control.Label
        TimeSeriesAnalysisTab           matlab.ui.container.Tab
        TimeSeriesChartPanel            matlab.ui.container.Panel
        UIAxes6                         matlab.ui.control.UIAxes
        TimeSeriesChartControlPanel     matlab.ui.container.Panel
        StartDateDatePickerLabel        matlab.ui.control.Label
        StartDateDatePicker             matlab.ui.control.DatePicker
        EndDateDatePickerLabel          matlab.ui.control.Label
        EndDateDatePicker               matlab.ui.control.DatePicker
        Series1DropDownLabel            matlab.ui.control.Label
        Series1DropDown                 matlab.ui.control.DropDown
        Series2DropDownLabel            matlab.ui.control.Label
        Series2DropDown                 matlab.ui.control.DropDown
        LogReturnsDistributionFitterPanel  matlab.ui.container.Panel
        CurrencyDropDownLabel           matlab.ui.control.Label
        CurrencyDropDown                matlab.ui.control.DropDown
        StartDateDatePicker_3Label      matlab.ui.control.Label
        StartDateDatePicker_3           matlab.ui.control.DatePicker
        OverlayDistributionCheckBox     matlab.ui.control.CheckBox
        DistributionTypeDropDownLabel   matlab.ui.control.Label
        DistributionTypeDropDown        matlab.ui.control.DropDown
        TextArea                        matlab.ui.control.TextArea
        UIAxes7                         matlab.ui.control.UIAxes
        UITable5                        matlab.ui.control.Table
        CorrelationMapPanel             matlab.ui.container.Panel
        CorrelationMapandSummaryStatisticsControlPanel  matlab.ui.container.Panel
        StartDateDatePicker_2Label      matlab.ui.control.Label
        StartDateDatePicker_2           matlab.ui.control.DatePicker
        EndDateDatePicker_2Label        matlab.ui.control.Label
        EndDateDatePicker_2             matlab.ui.control.DatePicker
        BackgroundColorButton           matlab.ui.control.StateButton
        VaRCalculationandBacktestingTab  matlab.ui.container.Tab
        UITable6                        matlab.ui.control.Table
        StepladderCurrencyDropDownLabel  matlab.ui.control.Label
        StepladderCurrencyDropDown      matlab.ui.control.DropDown
        Button                          matlab.ui.control.Button
        SamplingPeriodDropDownLabel     matlab.ui.control.Label
        SamplingPeriodDropDown          matlab.ui.control.DropDown
        RiskHorizonEditFieldLabel       matlab.ui.control.Label
        RiskHorizonEditField            matlab.ui.control.NumericEditField
        TextArea_2Label                 matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        UITable7                        matlab.ui.control.Table
        UITable8                        matlab.ui.control.Table
        VaRandESMethodDropDownLabel     matlab.ui.control.Label
        VaRandESMethodDropDown          matlab.ui.control.DropDown
        VaRBacktestingHorizonDatePickerLabel  matlab.ui.control.Label
        VaRBacktestingHorizonDatePicker  matlab.ui.control.DatePicker
        VaRLimitMlnEditFieldLabel       matlab.ui.control.Label
        VaRLimitMlnEditField            matlab.ui.control.NumericEditField
        SamplingRollingWindowDropDownLabel  matlab.ui.control.Label
        SamplingRollingWindowDropDown   matlab.ui.control.DropDown
        StartDateDatePicker_4Label      matlab.ui.control.Label
        StartDateDatePicker_4           matlab.ui.control.DatePicker
        EndDateDatePicker_3Label        matlab.ui.control.Label
        EndDateDatePicker_3             matlab.ui.control.DatePicker
        UIAxes8                         matlab.ui.control.UIAxes
        UIAxes9                         matlab.ui.control.UIAxes
    end

    
%     Dichiarazione Properties
    
    properties (Access = public)
        RateDialog % Rate and Settle Panel 
        NoRatesSetERROR % sconosciuto
        CurrencySetError % Error when setting option base currency with empty rates data
    end
    
    properties (Access = public)
        Q  % Q strumenti in AddtoPortfolio Callback
        Instr % Strumenti
        counter = 0 % contatore push AddtoPortfolio
        counter2 = 0 %Conta il numero di eliminazioni di strumenti uguali
        Pricer %Pricer
        OptionTypeCategorical
        OptionTypeCategoricalLong
        OptionTypeCategoricalShort % Crea un array di strumenti
        ShortInstrumentCounter = 0
        LongInstrumentCounter = 0
        TestInstr 
        Settle % Variabile temporanea per salvare la data dalla finestra dialogo
        ZeroCurveEUR
        ZeroCurveUSD
        ZeroCurveAUD
        ZeroCurveGBP
        Dates 
        Rates
        Currency
       
    end
    
    properties (Access = private)
        FredData
        Ret % DATA LOG RETURNS, da usare nell'heatmap
        RetTable
        InstSens % Passa i Dati al VaR e Stepladder
        Prices
        RetMatrix
        PriceMatrix
    end


%     Dichiarazione Funzioni


    methods (Access = public)
       
    end
    
    methods (Access = private)
        
        function [PortPrice, InstPrice, PortSens, InstSens] = PortCreationPricing(app,Instruments,Q,Pricer)


            myport = finportfolio(Instruments,Pricer,Q);

            [PortPrice, InstPrice, PortSens, InstSens] = pricePortfolio(myport);

            
        end
        
        
        function ReSetHomePlots(app)
            app.UITable2.Data = [];
            app.UITable3.Data = [];
            app.UITable4.Data = [];
            p = pie(app.UIAxes2,categorical(cellstr('No Instruments')));
            p(1).EdgeColor = 'w';
            p(2).Color = 'w';
            p = pie(app.UIAxes3,categorical(cellstr('No Instruments')));
            p(1).EdgeColor = 'w';
            p(2).Color = 'w';
            p = pie(app.UIAxes4,categorical(cellstr('No Instruments')));
            p(1).EdgeColor = 'w';
            p(2).Color = 'w';
            p = pie(app.UIAxes5,categorical(cellstr('No Instruments')));
            p(1).EdgeColor = 'w';
            p(2).Color = 'w';
            colormap(app.UIAxes2,parula(0));
            colormap(app.UIAxes3,parula(0));
            colormap(app.UIAxes4,parula(0));
            colormap(app.UIAxes5,parula(0));
            
        end
        
        
        function UpdateHomePlots(app,InstPrice,InstSens,PortSens)
            
%     Creazione Font
            fontstyle = uistyle;
            fontstyle.FontWeight = 'bold';
            fontstyleAl = uistyle;
            fontstyleAl.HorizontalAlignment = 'center';
            
%     Dati pos long     

            app.UITable2.Data = InstSens(InstSens.Price > 0,:);
            HeightTable = height(app.UITable2.Data);
            c2 = parula(HeightTable);
            for i = 1:HeightTable
                s = uistyle("BackgroundColor",c2(i,:));
                s.FontColor = c2(i,:);              
                addStyle(app.UITable2,s,'cell',[i 17])  %modificare numero della colonna quando si aggiungeranno le altre
            end
            addStyle(app.UITable2,fontstyle,'column',7:16)
            addStyle(app.UITable2,fontstyleAl,'column', 7:16)
            
            app.UITable2.ColumnName = InstSens.Properties.VariableNames;
            
            %Dati pos short
            app.UITable3.ColumnName = InstSens.Properties.VariableNames;
            app.UITable3.Data = InstSens(InstPrice < 0,:);
              HeightTable = height(app.UITable3.Data);
            c3 = parula(HeightTable);
            for i = 1:HeightTable
                s = uistyle("BackgroundColor",c3(i,:));
                s.FontColor = c3(i,:);              
                addStyle(app.UITable3,s,'cell',[i 17])  %modificare numero della colonna quando si aggiungeranno le altre
            end
            addStyle(app.UITable3,fontstyle,'column',7:16)
            addStyle(app.UITable3,fontstyleAl,'column', 7:16)
            
            %Dati globali Portafoglio
            app.UITable4.ColumnName = PortSens.Properties.VariableNames;
            app.UITable4.RowName = PortSens.Properties.RowNames;
            app.UITable4.Data = PortSens(:,[1:3 5:7]);
            
            
            TempPieShortData = -InstPrice(InstPrice <0);
            TempPieLongData = InstPrice(InstPrice > 0);
            
            %%%%%%%%%%%%%%%%%%%%%%%% Controllare
            if isempty(TempPieLongData) == 1
                TempPieLongData = categorical("No Instruments");
            end
            
            if isempty(TempPieShortData) == 1
                TempPieShortData = categorical("No Instruments");
            end
           
%       Plot Torte          
            PieLongValue = pie(app.UIAxes2,TempPieLongData);           
            colormap(app.UIAxes2,c2)
            
            PieShortValue = pie(app.UIAxes3,TempPieShortData);
            colormap(app.UIAxes3,c3)
            
            NumSegLong = length(PieLongValue);
            NumSegShort = length(PieShortValue);
            
            for i = 1:2:NumSegLong 
                PieLongValue(i).EdgeColor = 'w';
            end
            
            for k = 2:2:NumSegLong
                PieLongValue(k).Color = 'w';
            end
            
             for i = 1:2:NumSegShort 
                PieShortValue(i).EdgeColor = 'w';
            end
            
            for k = 2:2:NumSegShort
                PieShortValue(k).Color = 'w';
            end


%    If per creare i categorical per le torte. La torta riporta il
%    totale della categoria in rapporto al totale delle opzioni,
%    non al prezzo


%       If per evitare errori per mancanza di dati nei pie chart
            
           if app.LongInstrumentCounter > 0
              PieCatLong = pie(app.UIAxes4,categorical(app.OptionTypeCategoricalLong));
              colormap(app.UIAxes4,"autumn")
              NumSegLong = length(PieCatLong);
                for i = 1:2:NumSegLong 
                    PieCatLong(i).EdgeColor = 'w';
                end
            
                for k = 2:2:NumSegLong
                    PieCatLong(k).Color = 'w';
                end
              
           end


            if app.ShortInstrumentCounter > 0
                 PieCatShort = pie(app.UIAxes5,categorical(app.OptionTypeCategoricalShort));
                 colormap(app.UIAxes5,"autumn")                 
                 NumSegShort = length(PieCatShort); 
                for i = 1:2:NumSegShort 
                    PieCatShort(i).EdgeColor = 'w';
                end
            
                for k = 2:2:NumSegShort
                    PieCatShort(k).Color = 'w';
                end
           
            end


        end
        
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% NON SONO DISPONIBILI TUTTI I DATI MA POTREBBE SERVIRE        
        function  TimeSeriesDataImport(app)
            url = 'https://fred.stlouisfed.org/';
            c = fred(url);
            
            c.DataReturnFormat = 'table';
            c.DatetimeType = 'datetime';


% Fetch all available daily foreign exchange rates between the US dollar and the Euro using the series 'DEXUSEU'. The table d contains one row for the series. Each variable describes a piece of information about the series.

            series = {'DEXUSAL' 'DEXUSEU' 'DEXUSUK'};
            SeriesNames = {'USD/AUD' 'EUR/USD' 'USD/GBP' };
           
            d = fetch(c,series);
            
            app.FredData = d.Data;
           
            close(c)

        end
        
        
        function data = YahooData(app)
            
            series2 = {'AUD/GBP' 'EUR/GBP' 'AUD/EUR'};
            symbols = {'AUDGBP=X' 'EURGBP=X' 'AUDEUR=X'};
            
            for i = 1:length(symbols)
            data = getMarketDataViaYahooNew(symbols{i},datetime('01-01-1970',"Format","dd-MM-uuuu"));
            data.Var1 = data.Date;
            data.Var2 = data.Close;
            app.FredData{i+3} = data(:,{ 'Var1' 'Var2'});
            end
            
        end
            
        
        function TimeSeriesPlotUpdate(app)
            
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait: Starting Time Series Tab','Message','Updating Chart'); 
            
%%% PRIMO SELETTORE

            if app.Series1DropDown.Value == 'USD/AUD'
                Sel(1) = 1;
            else
                Sel(1) = 0;
            end
            
            if app.Series1DropDown.Value == 'EUR/USD'
                Sel(2) = 1;
            else
                Sel(2) = 0;
            end
            
            if app.Series1DropDown.Value == 'USD/GBP'
                Sel(3) = 1;
            else
                Sel(3) = 0;
            end
            
            if app.Series1DropDown.Value == 'AUD/GBP'
                Sel(4) = 1;
            else
                Sel(4) = 0;
            end
            
            if app.Series1DropDown.Value == 'EUR/GBP'
                Sel(5) = 1;
            else
                Sel(5) = 0;
            end
            
            if app.Series1DropDown.Value == 'AUD/EUR' 
                Sel(6) = 1;
            else
                Sel(6) = 0;
            end
            
%%%% SECONDO SELETTORE            
             
            if app.Series2DropDown.Value == 'USD/AUD'
                Sel2(1) = 1;
            else
                Sel2(1) = 0;
            end
            
            if app.Series2DropDown.Value == 'EUR/USD'
                Sel2(2) = 1;
            else
                Sel2(2) = 0;
            end
            
            if app.Series2DropDown.Value == 'USD/GBP'
                Sel2(3) = 1;
            else
                Sel2(3) = 0;
            end
            
            if app.Series2DropDown.Value == 'AUD/GBP'
                Sel2(4) = 1;
            else
                Sel2(4) = 0;
            end
            
            if app.Series2DropDown.Value == 'EUR/GBP'
                Sel2(5) = 1;
            else
                Sel2(5) = 0;
            end
            
            if app.Series2DropDown.Value == 'AUD/EUR' 
                Sel2(6) = 1;
            else
                Sel2(6) = 0;
            end
            

            d.Value = 0.40;
            d.Message = 'Retrieving Data';
            
            Plot_Data1 = app.FredData{logical(Sel)};
            Min_Date1 = min(Plot_Data1.Var1);
            app.StartDateDatePicker.Limits = [Min_Date1 datetime("today")];
            
            
            d.Value = 0.5;
            d.Message = 'Generating Charts';
            
            
            if app.Series2DropDown.Value == 'No plot'
                yyaxis(app.UIAxes6,"right")
                cla(app.UIAxes6)
                app.UIAxes6.YColor = [0.15 0.15 0.15];
                yyaxis(app.UIAxes6,"left")
                
            
                plot(app.UIAxes6,Plot_Data1.Var1(Plot_Data1.Var1 > app.StartDateDatePicker.Value & Plot_Data1.Var1 < app.EndDateDatePicker.Value),Plot_Data1.Var2(Plot_Data1.Var1 > app.StartDateDatePicker.Value & Plot_Data1.Var1 < app.EndDateDatePicker.Value))
            else if app.Series1DropDown.Value == app.Series2DropDown.Value
                  yyaxis(app.UIAxes6,"right")
                cla(app.UIAxes6)
                app.UIAxes6.YColor = [0.15 0.15 0.15];
                yyaxis(app.UIAxes6,"left")
                    plot(app.UIAxes6,Plot_Data1.Var1(Plot_Data1.Var1 > app.StartDateDatePicker.Value & Plot_Data1.Var1 < app.EndDateDatePicker.Value),Plot_Data1.Var2(Plot_Data1.Var1 > app.StartDateDatePicker.Value & Plot_Data1.Var1 < app.EndDateDatePicker.Value))
                else
            
                Plot_Data2 = app.FredData{logical(Sel2)};
                UniqueData = unique([Plot_Data1{:,1}; Plot_Data2{:,1}]);
                [~, in1] = intersect(UniqueData,Plot_Data1.Var1);
                [~, in2] = intersect(UniqueData,Plot_Data2.Var1);
                
                TempTABLE = table();
                TempTABLE.Date = UniqueData;
                TempTABLE.Var1(in1) = Plot_Data1.Var2;
                TempTABLE.Var2(in2) = Plot_Data2.Var2;
                
                
                Min_Date2 = min(UniqueData);
                app.StartDateDatePicker.Limits = [Min_Date2 datetime("today")];
                 
                MatrixTemp = table2array(TempTABLE(:,[2 3]));
                index = MatrixTemp == 0;
                [row,col] = find(index);
            
                if nnz(index) >0
                for i = 1:height(row)
                    TempTABLE{row(i),col(i)+1} = nan;
                end
                end
                
                FXNAME = [{'USD/AUD' 'EUR/USD' 'USD/GBP' } {'AUD/GBP' 'EUR/GBP' 'AUD/EUR'}];
                
                d.Value = 0.6;
                d.Message = 'Generating Charts';
                
                yyaxis(app.UIAxes6,'left')
                plot(app.UIAxes6,TempTABLE{TempTABLE{:,1} > app.StartDateDatePicker.Value & TempTABLE{:,1} < app.EndDateDatePicker.Value,1},TempTABLE{TempTABLE{:,1} > app.StartDateDatePicker.Value & TempTABLE{:,1} < app.EndDateDatePicker.Value,2})
                ylabel(app.UIAxes6,FXNAME{logical(Sel)})
                
                
                d.Value = 0.85;
                d.Message = 'Generating Charts';
                
                
                yyaxis(app.UIAxes6,"right")
                cla(app.UIAxes6)
                app.UIAxes6.YColor = [0.85 0.33 .1]
                plot(app.UIAxes6,TempTABLE{TempTABLE{:,1} > app.StartDateDatePicker.Value & TempTABLE{:,1} < app.EndDateDatePicker.Value,1},TempTABLE{TempTABLE{:,1} > app.StartDateDatePicker.Value & TempTABLE{:,1} < app.EndDateDatePicker.Value,3})
                ylabel(app.UIAxes6,FXNAME{logical(Sel2)})
                
                
                end
            end
            
            
                d.Value = 1;
                d.Message = 'Charts Generated Successfully';


        end
        
        
        function RetTable = Heatmap(app)
            %     HEATMAP
            
            for i= 1:length(app.FredData)
                data = app.FredData{i};
                app.Prices{i} = data.Var2;
                app.Ret{i} = dlog((app.Prices{i}));
            end

%     UNIONE DELLE DATE PER CREARE UNA TABELLA UNICA DI PREZZI E RENDIMENTI            
            
            UniqueDates = unique([app.FredData{1}.Var1 ; app.FredData{2}.Var1 ; app.FredData{3}.Var1 ; app.FredData{4}.Var1 ; app.FredData{5}.Var1 ; app.FredData{6}.Var1]);
            [~, in1] = intersect(UniqueDates,app.FredData{1}.Var1);
            [~, in2] = intersect(UniqueDates,app.FredData{2}.Var1);
            [~, in3] = intersect(UniqueDates,app.FredData{3}.Var1);
            [~, in4] = intersect(UniqueDates,app.FredData{4}.Var1);
            [~, in5] = intersect(UniqueDates,app.FredData{5}.Var1);
            [~, in6] = intersect(UniqueDates,app.FredData{6}.Var1);
            
            
             TempPrice_TABLE = table();
             TempPrice_TABLE.Date = UniqueDates;
             TempPrice_TABLE.Var1(in1) = app.Prices{1};
             TempPrice_TABLE.Var2(in2) = app.Prices{2};
             TempPrice_TABLE.Var3(in3) = app.Prices{3};
             TempPrice_TABLE.Var4(in4) = app.Prices{4};
             TempPrice_TABLE.Var5(in5) = app.Prices{5};
             TempPrice_TABLE.Var6(in6) = app.Prices{6};
            
             TempCorrelation_TABLE = table();
             TempCorrelation_TABLE.Date = UniqueDates;
             TempCorrelation_TABLE.Var1(in1) = app.Ret{1};
             TempCorrelation_TABLE.Var2(in2) = app.Ret{2};
             TempCorrelation_TABLE.Var3(in3) = app.Ret{3};
             TempCorrelation_TABLE.Var4(in4) = app.Ret{4};
             TempCorrelation_TABLE.Var5(in5) = app.Ret{5};
             TempCorrelation_TABLE.Var6(in6) = app.Ret{6};
    
% ELIMINAZIONE 0 E TRASFORMAZIONE IN NaN             
             TempMatrix = table2array(TempCorrelation_TABLE(TempCorrelation_TABLE.Date > app.StartDateDatePicker_2.Value & TempCorrelation_TABLE.Date < app.EndDateDatePicker_2.Value ,2:end));
             
               
                index = TempMatrix == 0;
                [row,col] = find(index);
            
                if nnz(index) >0
                    for i = 1:height(row)
                        TempMatrix(row(i),col(i)) = nan;
                    end
                end
                
% TRASFORMAZIONE PREZZI IN TABELLA UNICA (NON USATO NELLA CORRELATION MATRIX)

                TempMatrixPrice = table2array(TempPrice_TABLE(:,2:end));
             
               
                index = TempMatrixPrice == 0;
                [row,col] = find(index);
            
                if nnz(index) >0
                    for i = 1:height(row)
                        TempMatrixPrice(row(i),col(i)) = nan;
                    end
                end


             FXNAME = [{'USD/AUD' 'EUR/USD' 'USD/GBP' } {'AUD/GBP' 'EUR/GBP' 'AUD/EUR'}];
            app.StartDateDatePicker_2.Limits = [min(UniqueDates) max(UniqueDates)];
            corrMatr = corr(rmmissing(TempMatrix));
            heatmap(app.CorrelationMapPanel,FXNAME,FXNAME,corrMatr,"ColorbarVisible","on","Colormap",autumn(8))
            
            app.RetTable = TempCorrelation_TABLE;
            app.RetMatrix = TempMatrix;
            app.PriceMatrix = TempMatrixPrice;
            %Calcolo Heatmap
        end
            
        
        function  HistTimeSeries(app,RetTable)
            
            
            %GEnerazione Istogramma
                app.StartDateDatePicker_3.Limits = [min(RetTable.Date) max(RetTable.Date)];
                histData = RetTable{RetTable.Date > app.StartDateDatePicker_3.Value,app.CurrencyDropDown.Value+1}; 
            
                histogram(app.UIAxes7,histData,'FaceColor','r','EdgeColor','w',"BinEdges",linspace(-0.04,0.04,75),"Normalization","pdf");
                xlim = [-0.04 0.04];
                
                hold(app.UIAxes7,"on")


            if app.OverlayDistributionCheckBox.Value == true
                app.DistributionTypeDropDown.Enable = 'on';


                x = linspace(-0.05,0.05,500);
                if app.DistributionTypeDropDown.Value == 1
                    pd = fitdist(histData,'Normal');
                    y = pdf(pd,x);
                    plot(app.UIAxes7,x,y,"Color",'y',"LineStyle","--","LineWidth",1)
                    app.TextArea.Value = ["Normal Distribution Fitted with parameters:", strjoin(["mu = " string(pd.mu)]) , strjoin(["sigma = " string(pd.sigma)])];
                else
                    
                    pd = fitdist(histData,'tLocationScale');
                    y = pdf(pd,x);
                    plot(app.UIAxes7,x,y,"Color",'y',"LineStyle","--","LineWidth",1)
                    app.TextArea.Value = ["T-Student Distribution Fitted with parameters:", strjoin(["mu = " string(pd.mu)]) , strjoin(["sigma = " string(pd.sigma)]), strjoin(["nu = " string(pd.nu)])];
                end
                
            else
                app.DistributionTypeDropDown.Enable = 'off';
                app.TextArea.Value ="";
                
            end
            
            hold(app.UIAxes7,"off")
            
        end


        function SummaryStatistics(app)
            %% HeatMap e Summary statistic


            qvec = [0.01 0.05 0.10 0.25 0.5 0.75 0.90 0.95 0.99];
               
                meanv = mean(rmmissing(app.RetMatrix));
                vartt = var(rmmissing(app.RetMatrix));
                maxtt = max(rmmissing(app.RetMatrix));
                mintt = min(rmmissing(app.RetMatrix));
                stdtt = std(rmmissing(app.RetMatrix));
                skewtt = skewness(rmmissing(app.RetMatrix));
                kurtosistt = kurtosis(rmmissing(app.RetMatrix));
                
                quant_tt = quantile(rmmissing(app.RetMatrix),qvec)';
            
            
            tempTable = table(meanv',vartt',stdtt',maxtt',mintt',skewtt',kurtosistt');
            app.UITable5.Data = tempTable;
            
            for i = 1:length(quant_tt)
            tempTable{:,i+7} = quant_tt(:,i);
            end
            
            FXNAME = [{'USD/AUD' 'EUR/USD' 'USD/GBP' } {'AUD/GBP' 'EUR/GBP' 'AUD/EUR'}];
            
            for i = 1:length(qvec)
                quantileNames(i) = (strjoin(["Q." num2str(qvec(i))]));
            end
            
            
            app.UITable5.Data = tempTable;
            app.UITable5.RowName = FXNAME;
            app.UITable5.ColumnName = ["Mean" "Variance" "Std Dev" "Max" "Min" "Skew" "Kurtosis" quantileNames];
            
        end
    
            
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
            pause(5)
            d = uiprogressdlg(app.UIFigure,"Message",'Starting Dashboard',"Title",'Please Wait: Dashboard Starting');
          
          
            %Disabilita i campi inserimento non per la plain vanilla
            app.BarrierValueEditField.Enable = 'off';
            app.BinaryValueEditField.Enable = 'off';
            app.BarrierTypeDropDown.Enable = 'off';
            app.OptionMaturityDatePicker.Limits = [datetime('01-01-1999',"Format","dd-MM-uuuu") datetime('01-01-2100',"Format","dd-MM-uuuu")];
            app.EndDateDatePicker.Value = datetime("today");
           
 
            d.Value = .20;
            d.Message = 'Initializing Charts';
            %Grafici a torta inizializzazione
            ReSetHomePlots(app)
            
            
            %imposta formato decimali
            format bank;
            d.Value = .30;
            d.Message = ['Getting Data from Yahoo Finance and FRED ' char(174)];
            %Plot dati cambio su grafico
            TimeSeriesDataImport(app);
            YahooData(app)
            TimeSeriesPlotUpdate(app);
           
            d.Value = .40; 
           d.Message = 'Creating Correlation Heatmap';
            
% Call Generating Function
            Heatmap(app);            
            
            d.Value = .7;
            d.Message = 'Generating Summary Statistics';
            
            SummaryStatistics(app)

            
% Generate Return Distribution
            
            HistTimeSeries(app,app.RetTable)

            d.Value = 1;
            d.Message = 'Operations Completed';
            pause(0.5)
        end

        % Button pushed function: AddtoPortfolioButton
        function AddtoPortfolioButtonPushed(app, event)
             app.counter = app.counter +1;
             d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Adding Option to Portfolio'); 
                       
             
%             Le Variabili ricevono i dati dagli elementi interattivi della
%             %dashboard
            OptionType = app.InstrumentTypeDropDown.Value;
            Strike = app.StrikeEditField.Value;
            LongShort = app.PositionButtonGroup.SelectedObject.Text;
            CallPut = app.OptionTypeButtonGroup.SelectedObject.Text;
            BinaryValue = app.BinaryValueEditField.Value;
            BarrierValue = app.BarrierValueEditField.Value;
            BarrierType = app.BarrierTypeDropDown.Value;
            MaturityDate = app.OptionMaturityDatePicker.Value;
            OptionStyle = app.OptionStyleButtonGroup.SelectedObject.Text;
            class(OptionStyle)


d.Value = .33; 
d.Message = 'Creating Portfolio';


%             If per cambio segno alla quantità per avere posizioni long e
%             short
            Test = 'Long';
            if strcmp(LongShort,Test) == 1
                app.Q = [app.Q app.QuantitySpinner.Value]; %riferimento a property perchè già esistente
                app.OptionTypeCategoricalLong = [app.OptionTypeCategoricalLong repmat(string(OptionType),1,app.QuantitySpinner.Value)];
                
                app.LongInstrumentCounter = app.LongInstrumentCounter +1;
            else
                app.Q = [app.Q -app.QuantitySpinner.Value];
                app.OptionTypeCategoricalShort = [app.OptionTypeCategoricalShort repmat(string(OptionType),1,app.QuantitySpinner.Value)];
                app.ShortInstrumentCounter = app.ShortInstrumentCounter+1;
                LongShort = 'Short';
            end
            
%             Stringa con nome strumento
            Name = OptionType;
            app.OptionTypeCategorical = [app.OptionTypeCategorical categorical(upper(cellstr(OptionType)))];
            
%             Vari if per definire lo strumento da aggiungere
            if strcmp(OptionType,'Vanilla') == 1
            app.Instr = [app.Instr fininstrument(OptionType,"OptionType",CallPut,"Strike",Strike,"ExerciseDate",MaturityDate,'ExerciseStyle',OptionStyle, 'Name',Name)];
            
            end
            
            if strcmp(OptionType,'Binary') == 1
                app.Instr = [app.Instr fininstrument(OptionType,"OptionType",CallPut,"PayoffValue",BinaryValue,"Strike",Strike,"ExerciseDate", MaturityDate,'Name',Name)];
            end
            
            if strcmp(OptionType,'Barrier') == 1
                app.Instr = [app.Instr fininstrument(OptionType,"OptionType",CallPut,"BarrierValue",BarrierValue,"BarrierType",BarrierType,"Strike",Strike,"ExerciseDate",MaturityDate, 'Name',Name,"ExerciseStyle",OptionStyle)];
            end
            
            if strcmp(OptionType, 'Lookback') == 1
                app.Instr = [app.Instr fininstrument(OptionType,"OptionType",CallPut,"Strike",Strike,"ExerciseDate",MaturityDate, 'Name',Name,"ExerciseStyle",OptionStyle)];
            end
           
            
     d.Value = .38; 
    d.Message = 'Option Added';       


%             Costruzione Pricer, anteposta per controllo degli strumenti ed
%             eliminazione
            Settle = app.Settle;


            BLSModel = finmodel("BlackScholes","Volatility",app.VolatilityEditField.Value);


% EUR CASE            
            if app.BaseCurrencyDropDown.Value(1:3) == 'EUR'
                if or((strcmp(OptionType,'Lookback') == 1),(strcmp(OptionStyle,'American') == 1))  
                    app.Pricer = [app.Pricer finpricer("AssetMonteCarlo",'DiscountCurve',app.ZeroCurveEUR,"Model",BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous",'simulationDates',Settle+days(1):days(1):MaturityDate,'NumTrials',app.SimulationTrialsEditField.Value)];
                else
                    app.Pricer = [app.Pricer finpricer("analytic","DiscountCurve",app.ZeroCurveEUR,'Model',BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous")];
                end
            end

% USD CASE            
            if app.BaseCurrencyDropDown.Value(1:3) == 'USD'
                if or((strcmp(OptionType,'Lookback') == 1),(strcmp(OptionStyle,'American') == 1))  
                    app.Pricer = [app.Pricer finpricer("AssetMonteCarlo",'DiscountCurve',app.ZeroCurveUSD,"Model",BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous",'simulationDates',Settle+days(1):days(1):MaturityDate,'NumTrials',app.SimulationTrialsEditField.Value)];
                else
                    app.Pricer = [app.Pricer finpricer("analytic","DiscountCurve",app.ZeroCurveUSD,'Model',BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous")];
                end
            end

            
% AUD CASE            
            if app.BaseCurrencyDropDown.Value(1:3) == 'AUD'
                if or((strcmp(OptionType,'Lookback') == 1),(strcmp(OptionStyle,'American') == 1))  
                    app.Pricer = [app.Pricer finpricer("AssetMonteCarlo",'DiscountCurve',app.ZeroCurveAUD,"Model",BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous",'simulationDates',Settle+days(1):days(1):MaturityDate,'NumTrials',app.SimulationTrialsEditField.Value)];
                else
                    app.Pricer = [app.Pricer finpricer("analytic","DiscountCurve",app.ZeroCurveAUD,'Model',BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous")];
                end
            end
            
% GBP CASE            
            if app.BaseCurrencyDropDown.Value(1:3) == 'GBP'
                if or((strcmp(OptionType,'Lookback') == 1),(strcmp(OptionStyle,'American') == 1))  
                    app.Pricer = [app.Pricer finpricer("AssetMonteCarlo",'DiscountCurve',app.ZeroCurveGBP,"Model",BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous",'simulationDates',Settle+days(1):days(1):MaturityDate,'NumTrials',app.SimulationTrialsEditField.Value)];
                else
                    app.Pricer = [app.Pricer finpricer("analytic","DiscountCurve",app.ZeroCurveGBP,'Model',BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous")];
                end
            end
            
   
d.Value = .45; 
    d.Message = 'Pricing Model Created';            
            
            
           app.Currency = [app.Currency string(app.BaseCurrencyDropDown.Value)];
           
    
            app.TestInstr(1) = 0;
            if app.counter == 1
            else 
                for i = 1:app.counter-app.counter2-1
                    if (app.Instr(i) == app.Instr(app.counter-app.counter2)) & (app.Q(i)*app.Q(app.counter-app.counter2) > 0) & (app.Currency(i) == app.BaseCurrencyDropDown.Value)
                        app.TestInstr(i) = 1;
                    else
                        app.TestInstr(i) = 0;
                    end
                end
            end
            
            if nnz(app.TestInstr) > 0
                app.Currency(app.counter-app.counter2) = [];
                app.Instr(app.counter-app.counter2) = [];
                app.Pricer(app.counter-app.counter2) = [];
                app.Q(logical([app.TestInstr 0])) = app.Q(logical([app.TestInstr 0]))+ app.Q(app.counter-app.counter2);
                app.Q(app.counter-app.counter2) = [];
                app.OptionTypeCategorical(app.counter-app.counter2) = [];
                app.counter2 = app.counter2+1;
            end


if strcmp(OptionStyle,'American') == 1            
d.Value = .55; 
    d.Message = 'Simulating Underlying Paths';
else
    d.Value = .55; 
    d.Message = 'Black-Scholes Pricing';
end            
            
            Qty = app.Q;
            Instrum = app.Instr;
            
%             
%             Costruzione Pricer
%             

            
            [~, InstPrice, PortSens, app.InstSens] = PortCreationPricing(app,Instrum,Qty,app.Pricer);


if strcmp(OptionStyle,'American') == 1            
d.Value = .75; 
    d.Message = 'Simulation Completed';
else
    d.Value = .75; 
    d.Message = 'Option Correctly Priced';          
end            
            
%      Creazione Variabili info strumenti       
            for i = 1: length(app.Instr)
                OptionTypetoTABLE(i,1) = categorical(upper(app.Instr(i).OptionType));
                StriketoTABLE(i,1) = app.Instr(i).Strike;
                StyletoTABLE(i,1) = app.Instr(i).ExerciseStyle;
                DatetoTABLE(i,1) = app.Instr(i).ExerciseDate;
            
                if strcmp(app.OptionTypeCategorical(1,i),'BARRIER') == 1 %Maiuscolo perchè sopra c'è upper nella creazione del vettore
                    BValuetoTABLE(i,1) = app.Instr(i).BarrierValue;
                    BTypetoTABLE(i,1) = app.Instr(i).BarrierType;
                else
                    BValuetoTABLE(i,1) = '-';
                    BTypetoTABLE(i,1) = '-';
                
                end
                
                if strcmp(app.OptionTypeCategorical(1,i),'BINARY') == 1
                    BinVatoTABLE(i,1) = app.Instr(i).BinaryValue;
                else
                    BinVatoTABLE(i,1) = '-';
                end


            end
%     Creazione colonne nelle tabelle 
            app.InstSens.Quantity = app.Q';
            app.InstSens.Currency = categorical(app.Currency');
            app.InstSens.OptionType = app.OptionTypeCategorical';
            app.InstSens.Style = upper(StyletoTABLE);
            app.InstSens.CallPut = OptionTypetoTABLE;
            app.InstSens.Strike = StriketoTABLE;
            app.InstSens.BarrierValue = BValuetoTABLE;
            app.InstSens.BarrierType = BTypetoTABLE;
            app.InstSens.BinaryValue = BinVatoTABLE;
            app.InstSens.Maturity = DatetoTABLE;
            app.InstSens.Legend = zeros([height(app.InstSens(:,1)) 1]);
            app.InstSens.Lambda = [];
            app.InstSens.Properties.RowNames = {};


    d.Value = .95; 
    d.Message = 'Generating Tables and Charts';             


%       Update Plots
            
            UpdateHomePlots(app,InstPrice,app.InstSens,PortSens)


    d.Value = .100; 
    d.Message = 'Option Added Succesfully'; 
    pause(0.5)
        end

        % Value changed function: InstrumentTypeDropDown
        function InstrumentTypeDropDownValueChanged(app, event)
            value = app.InstrumentTypeDropDown.Value;
            if strcmp(value,'Barrier') == 1
                app.BarrierValueEditField.Enable = 'on';
                app.BarrierTypeDropDown.Enable = 'on';
            else
                app.BarrierValueEditField.Enable = 'off'; 
                app.BarrierTypeDropDown.Enable = 'off';
            end
            
            
            if strcmp(value,'Binary') == 1
                app.BinaryValueEditField.Enable = 'on';
                app.OptionStyleButtonGroup.Enable = 'off';
                app.EuropeanButton.Value = true;
            else
                app.BinaryValueEditField.Enable = 'off';
                app.OptionStyleButtonGroup.Enable = 'on';
            end
            
        
        end

        % Button pushed function: DeletePortfolioButton
        function DeletePortfolioButtonPushed(app, event)
            app.Instr = [];
            app.Q = [];
            app.Pricer = [];
            app.counter = 0;
            app.counter2 = 0;
            app.TestInstr = [];
            app.ShortInstrumentCounter = 0;
            app.LongInstrumentCounter = 0;
            app.OptionTypeCategoricalLong = [];
            app.OptionTypeCategoricalShort = [];
            app.OptionTypeCategorical = [];
            app.Currency = [];
%        Reset Plots and Tables
            ReSetHomePlots(app)
            
        end

        % Button pushed function: SetDateandRatesButton
        function SetDateandRatesButtonPushed(app, event)
            app.SetDateandRatesButton.Enable = 'off';
            app.RateDialog = RateandSettleDialog(app);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            delete(app.RateDialog)
            delete(app.CurrencySetError)
            delete(app)


        end

        % Value changed function: BaseCurrencyDropDown
        function BaseCurrencyDropDownValueChanged(app, event)
            value = app.BaseCurrencyDropDown.Value(1:3);
            
% IMPOSTAZIONE DELL?UNDERLYING PRICE COME ULTIMO TASSO DI CAMBIO REGISTRATO
            
           
            if isempty(app.ZeroCurveEUR) == 1
                TEST(1) = 0;
            else
                TEST(1) = 1;
            end
            
            if isempty(app.ZeroCurveUSD) == 1
                TEST(2) = 0;
            else
                TEST(2) = 1;
            end
            
            if isempty(app.ZeroCurveAUD) == 1
                TEST(3) = 0;
            else
                TEST(3) = 1;
            end
            
            if isempty(app.ZeroCurveGBP) == 1
                TEST(4) = 0;
            else
                TEST(4) = 1;
            end
            
            CurrencyString = ["EUR" "USD" "AUD" "GBP"];
            
            
% Aggiungere errori            
            if TEST(1) == 0 & value == 'EUR'
               app.CurrencySetError = CurrencySetError(app);
               ind = find(TEST,1);
               app.BaseCurrencyDropDown.Value(1:3) = CurrencyString(ind);
            end
            
            if TEST(2) == 0 & value == 'USD'
               app.CurrencySetError = CurrencySetError(app);
               ind = find(TEST,1);
               app.BaseCurrencyDropDown.Value(1:3) = CurrencyString(ind);
            end
            
            
            if TEST(3) == 0 & value == 'AUD'
               app.CurrencySetError = CurrencySetError(app);
               ind = find(TEST,1);
               app.BaseCurrencyDropDown.Value(1:3) = CurrencyString(ind);
            end
            
            
            if TEST(4) == 0 & value == 'GBP'
               app.CurrencySetError = CurrencySetError(app);
               ind = find(TEST,1);
               app.BaseCurrencyDropDown.Value(1:3) = CurrencyString(ind);
            end


        end

        % Value changed function: Series2DropDown
        function Series2DropDownValueChanged(app, event)
            value = app.Series2DropDown.Value;
            TimeSeriesPlotUpdate(app)
        end

        % Value changed function: Series1DropDown
        function Series1DropDownValueChanged(app, event)
            value = app.Series1DropDown.Value;
            TimeSeriesPlotUpdate(app)
        end

        % Value changed function: StartDateDatePicker
        function StartDateDatePickerValueChanged(app, event)
            value = app.StartDateDatePicker.Value;
            TimeSeriesPlotUpdate(app)
            
        end

        % Value changed function: EndDateDatePicker
        function EndDateDatePickerValueChanged(app, event)
            value = app.EndDateDatePicker.Value;
            TimeSeriesPlotUpdate(app)
            
        end

        % Value changed function: StartDateDatePicker_2
        function StartDateDatePicker_2ValueChanged(app, event)
            value = app.StartDateDatePicker_2.Value;
            Heatmap(app)
            SummaryStatistics(app)
        end

        % Value changed function: EndDateDatePicker_2
        function EndDateDatePicker_2ValueChanged(app, event)
            value = app.EndDateDatePicker_2.Value;
            Heatmap(app)
            SummaryStatistics(app)
        end

        % Value changed function: BackgroundColorButton
        function BackgroundColorButtonValueChanged(app, event)
            value = app.BackgroundColorButton.Value;
            if value == true
                app.BackgroundColorButton.BackgroundColor = [0.15 0.15 0.15];
                app.BackgroundColorButton.FontColor = [1 1 1];
                app.CorrelationMapPanel.ForegroundColor = [0 0 0];
                app.CorrelationMapPanel.BackgroundColor = [1 1 1];
            else
                app.BackgroundColorButton.BackgroundColor = [1 1 1];
                app.BackgroundColorButton.FontColor = [0 0 0];
                app.CorrelationMapPanel.ForegroundColor = [1 1 1];
                app.CorrelationMapPanel.BackgroundColor = [0.15 0.15 0.15];
            end
            
        end

        % Value changed function: CurrencyDropDown
        function CurrencyDropDownValueChanged(app, event)
            value = app.CurrencyDropDown.Value;
            HistTimeSeries(app,app.RetTable)
        end

        % Value changed function: StartDateDatePicker_3
        function StartDateDatePicker_3ValueChanged(app, event)
            value = app.StartDateDatePicker_3.Value;
            HistTimeSeries(app,app.RetTable)
        end

        % Value changed function: OverlayDistributionCheckBox
        function OverlayDistributionCheckBoxValueChanged(app, event)
            value = app.OverlayDistributionCheckBox.Value;
            
            HistTimeSeries(app,app.RetTable)
            
            
        end

        % Value changed function: DistributionTypeDropDown
        function DistributionTypeDropDownValueChanged(app, event)
            value = app.DistributionTypeDropDown.Value;
            
            
           HistTimeSeries(app,app.RetTable)
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            value = app.RiskHorizonEditField.Value;
            HistTimeSeries(app,app.RetTable)
        end

        % Selection changed function: OptionStyleButtonGroup
        function OptionStyleButtonGroupSelectionChanged(app, event)
            selectedButton = app.OptionStyleButtonGroup.SelectedObject.Text;
            
            if selectedButton == 'American'
                app.SimulationTrialsEditField.Enable = 'on';
            else
                app.SimulationTrialsEditField.Enable = 'off';
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
%CREAZIONE PORTAFOGLIO PER RICAVARE LE OPZIONI DENOMINATE NEL TASSO DI
%CAMBIO SELEZIONATO
for i = 1:length(app.Instr)
Portfolio{1,i} = app.Instr(i);
Portfolio{2,i} = app.Q(i);
Portfolio{3,i} = app.Pricer(i);
Portfolio{4,i} = app.Currency(i);
Portfolio{5,i} = replace(Portfolio{4,i},["USD/AUD" "EUR/USD" "USD/GBP" "AUD/GBP" "EUR/GBP" "AUD/EUR"],["1" "2" "3" "4" "5" "6"]);
Portfolio{5,i} = str2double(Portfolio{5,i});
end

%VETTORE LOGICO PER IDENTIFICARLE
for i = 1:size(Portfolio,2)
    if Portfolio{5,i} == app.StepladderCurrencyDropDown.Value
        TESTV(i) = 1;
    else
        TESTV(i) = 0;
    end
end

%CREAZIONE DEL PORTAFOGLIO CON LE OPZIONI RICHIESTE
Temp_Portfolio = Portfolio(1:3,logical(TESTV));

%CONVERSIONE IN UN PORTAFOGLIO CHE PUò ESSERE PREZZATO CON FINPORTFOLIO
for i = 1:size(Temp_Portfolio,2)
    TempInstruments(1,i) = Temp_Portfolio{1,i}; 
    TempQuantities(1,i) = Temp_Portfolio{2,i};
    TempPricer(1,i) = Temp_Portfolio{3,i};
end

TempTable = app.RetTable(app.RetTable.Date > datetime("today")-calmonths(app.SamplingPeriodDropDown.Value),:);
TempMatrix = table2array(TempTable(:,2:end));

                    index = TempMatrix == 0;
                [row,col] = find(index);
            
                if nnz(index) >0
                    for i = 1:height(row)
                        TempMatrix(row(i),col(i)) = nan;
                    end
                end


%CREAZIONE SCENARI
qvec = [0.001 0.01 0.05 0.10 0.25 0.5 0.75 0.90 0.95 0.99 0.999];
Quantili = quantile(rmmissing(TempMatrix(:,app.StepladderCurrencyDropDown.Value)),qvec);
S0Scenery= exp(Quantili.*sqrt(app.RiskHorizonEditField.Value))'.*app.Prices{app.StepladderCurrencyDropDown.Value}(end);
app.TextArea_2.Value = ["Current Underlying Price:" string(app.Prices{app.StepladderCurrencyDropDown.Value}(end))];


%APPLICAZIONE DEGLI SCENARI AI PRICER E AGGIUNTA DI UNO SCENARIO
%CORRISPONDENTE AL VALORE SPOT DEL TASSO DI CAMBIO (PORTAFOGLIO INIZIALE)

S0Scenery_StartingPrice = [S0Scenery ; app.Prices{app.StepladderCurrencyDropDown.Value}(end)];

for i = 1:length(S0Scenery_StartingPrice)
    for k= 1:length(TempPricer)
        TempPricer(k).SpotPrice = S0Scenery_StartingPrice(i);
    end
    [~, ~, PortSens, ~] = pricePortfolio(finportfolio(TempInstruments,TempPricer,TempQuantities));
    SummaryTable(i,:) = PortSens;
    
end

Loss = table(SummaryTable{1:end-1,1}-SummaryTable{end,1});

app.UITable6.Data = [table(S0Scenery,'VariableNames',"Scenery") table(log(S0Scenery_StartingPrice(1:end-1)./S0Scenery_StartingPrice(end)),'VariableNames',"Variation") Loss SummaryTable(1:end-1,:)];
app.UITable6.ColumnName = {'Scenarios' 'Gains/Losses' 'Var (%)' ' Portfolio Price' 'Delta' 'Gamma' 'Lambda' 'Vega' 'Theta' 'Rho'};

app.UITable7.Data = SummaryTable(end,:);
app.UITable7.ColumnName = {'Price' 'Delta' 'Gamma' 'Lambda' 'Vega' 'Theta' 'Rho'};
app.UITable7.RowName = {'Selected Currency Portfolio Data'};


sq = std(rmmissing(TempMatrix(:,app.StepladderCurrencyDropDown.Value)));
%VAR ES TABLE STATISTICAL APPROACH 
VarESQvec = [1-0.999 1-0.99 1-0.95]';

fit = fitdist(rmmissing(TempMatrix(:,app.StepladderCurrencyDropDown.Value)),"tLocationScale");
Qvec1 = [norminv(VarESQvec) tinv(VarESQvec,fit.nu) ];

%A SECONDA DELLA MODALITà SELEZIONATA, ELABORA DEGLI SCENARI DEL TASSO DI
%CAMBIO IN DIMINUZIONE


if SummaryTable.Delta(end) > 0 && SummaryTable.Price(end) > 0
    VAR_Scenerios = exp(sq.*Qvec1(:,app.VaRandESMethodDropDown.Value).*sqrt(app.RiskHorizonEditField.Value)).*app.Prices{app.StepladderCurrencyDropDown.Value}(end);
    
    if app.VaRandESMethodDropDown.Value == 1
    
        ES_Scenarios = exp(-VarESQvec.^-1 .* normpdf(norminv(VarESQvec))*sq.*sqrt(app.RiskHorizonEditField.Value)).*app.Prices{app.StepladderCurrencyDropDown.Value}(end);
    else
        tStud_PDF = tpdf(tinv(VarESQvec,fit.nu),fit.nu);
        shock = exp(-sq.*((fit.nu+Qvec1(:,2).^2)./(fit.nu-1)).*(tStud_PDF./(VarESQvec)).*sqrt(app.RiskHorizonEditField.Value));
        ES_Scenarios = shock.*app.Prices{app.StepladderCurrencyDropDown.Value}(end);
    end
end
if SummaryTable.Delta(end) < 0 && SummaryTable.Price(end) > 0
    VAR_Scenerios = exp(-sq.*Qvec1(:,app.VaRandESMethodDropDown.Value).*sqrt(app.RiskHorizonEditField.Value)).*app.Prices{app.StepladderCurrencyDropDown.Value}(end);
    
    if app.VaRandESMethodDropDown.Value == 1
    
        ES_Scenarios = exp(VarESQvec.^-1 .* normpdf(norminv(VarESQvec))*sq.*sqrt(app.RiskHorizonEditField.Value)).*app.Prices{app.StepladderCurrencyDropDown.Value}(end);
    else
        tStud_PDF = tpdf(tinv(VarESQvec,fit.nu),fit.nu);
        shock = exp(sq.*((fit.nu+Qvec1(:,2).^2)./(fit.nu-1)).*(tStud_PDF./(VarESQvec)).*sqrt(app.RiskHorizonEditField.Value));
        ES_Scenarios = shock.*app.Prices{app.StepladderCurrencyDropDown.Value}(end);
    end
end
if SummaryTable.Delta(end) > 0 && SummaryTable.Price(end) < 0
    
            VAR_Scenerios = exp(sq.*Qvec1(:,app.VaRandESMethodDropDown.Value).*sqrt(app.RiskHorizonEditField.Value)).*app.Prices{app.StepladderCurrencyDropDown.Value}(end);
    
    if app.VaRandESMethodDropDown.Value == 1
    
        ES_Scenarios = exp(-VarESQvec.^-1 .* normpdf(norminv(VarESQvec))*sq.*sqrt(app.RiskHorizonEditField.Value)).*app.Prices{app.StepladderCurrencyDropDown.Value}(end);
    else
        tStud_PDF = tpdf(tinv(VarESQvec,fit.nu),fit.nu);
        shock = exp(-sq.*((fit.nu+Qvec1(:,2).^2)./(fit.nu-1)).*(tStud_PDF./(VarESQvec)).*sqrt(app.RiskHorizonEditField.Value));
        ES_Scenarios = shock.*app.Prices{app.StepladderCurrencyDropDown.Value}(end);
    end
    
   
    end

if SummaryTable.Delta(end) < 0 && SummaryTable.Price(end) < 0
   VAR_Scenerios = exp(-sq.*Qvec1(:,app.VaRandESMethodDropDown.Value).*sqrt(app.RiskHorizonEditField.Value)).*app.Prices{app.StepladderCurrencyDropDown.Value}(end);
    
    if app.VaRandESMethodDropDown.Value == 1
    
        ES_Scenarios = exp(VarESQvec.^-1 .* normpdf(norminv(VarESQvec))*sq.*sqrt(app.RiskHorizonEditField.Value)).*app.Prices{app.StepladderCurrencyDropDown.Value}(end);
    else
        tStud_PDF = tpdf(tinv(VarESQvec,fit.nu),fit.nu);
        shock = exp(sq.*((fit.nu+Qvec1(:,2).^2)./(fit.nu-1)).*(tStud_PDF./(VarESQvec)).*sqrt(app.RiskHorizonEditField.Value));
        ES_Scenarios = shock.*app.Prices{app.StepladderCurrencyDropDown.Value}(end);
    end
end


ES_Scenarios_SpotPrice = [ES_Scenarios ; app.Prices{app.StepladderCurrencyDropDown.Value}(end) ];
VAR_Scenarios_SpotPrice = [VAR_Scenerios ; app.Prices{app.StepladderCurrencyDropDown.Value}(end)];

for i = 1:length(VAR_Scenarios_SpotPrice)
    for k= 1:length(TempPricer) 
        TempPricer(k).SpotPrice = VAR_Scenarios_SpotPrice(i);
    end
    [~, ~, PortSensVAR, ~] = pricePortfolio(finportfolio(TempInstruments,TempPricer,TempQuantities));
    SummaryTableVAR(i,:) = PortSensVAR;
    
end


for i = 1:length(ES_Scenarios_SpotPrice)
    for k= 1:length(TempPricer)
        TempPricer(k).SpotPrice = ES_Scenarios_SpotPrice(i);
    end
    [~, ~, PortSensES, ~] = pricePortfolio(finportfolio(TempInstruments,TempPricer,TempQuantities));
    SummaryTableES(i,:) = PortSensES;
    
end

ES = SummaryTableES{end,1}-SummaryTableES{1:end-1,1};
VaR= SummaryTableVAR{end,1}-SummaryTableVAR{1:end-1,1};
Alpha = ["99.9%" "99%" "95%"];


app.UITable8.Data = table(string(Alpha'),VAR_Scenarios_SpotPrice(1:end-1),log(VAR_Scenarios_SpotPrice(1:end-1)/VAR_Scenarios_SpotPrice(end)),VaR, ES_Scenarios_SpotPrice(1:end-1),log(ES_Scenarios_SpotPrice(1:end-1)/ES_Scenarios_SpotPrice(end)) , ES ,'VariableNames',{'Alpha' 'VaR Scenario' 'VaR Applied Shock (%)'  'VaR' 'ES Scenario' 'ES Applied Shock (%)'  'CVaR' });
app.UITable8.ColumnName = {'Alpha' 'VaR Scenario' 'VaR Applied Shock (%)'  'VaR' 'ES Scenario' 'ES Applied Shock (%)'  'ES' };

addStyle(app.UITable8,uistyle("HorizontalAlignment",'center'),'column',1:7);


%% CALCOLO ES
%CIAO = TempMatrix(TempMatrix(:,app.StepladderCurrencyDropDown.Value) < Qvec1(1),app.StepladderCurrencyDropDown.Value)
%ES99_9 = mean(rmmissing(CIAO));

%CIAO2 = TempMatrix(TempMatrix(:,app.StepladderCurrencyDropDown.Value) < Qvec1(2),app.StepladderCurrencyDropDown.Value)
%ES99 = mean(rmmissing(CIAO2));

%CIAO3 = TempMatrix(TempMatrix(:,app.StepladderCurrencyDropDown.Value) < Qvec1(3),app.StepladderCurrencyDropDown.Value)
%ES95 = mean(rmmissing(CIAO2));

StartDate = app.VaRBacktestingHorizonDatePicker.Value; 
TestWindow = StartDate:datetime("today");


F = 0;
for t = TestWindow
    
   F = F+1; 
    

VARTABLE = app.RetTable(app.RetTable.Date > t-calmonths(app.SamplingRollingWindowDropDown.Value) & app.RetTable.Date < t,:);
VARTempMatrix = table2array(VARTABLE(:,2:end));

                    index = VARTempMatrix == 0;
                [row,col] = find(index);
            
                if nnz(index) >0
                    for i = 1:height(row)
                        VARTempMatrix(row(i),col(i)) = nan;
                    end
                end


sq = std(rmmissing(VARTempMatrix(:,app.StepladderCurrencyDropDown.Value)));
%VAR ES TABLE STATISTICAL APPROACH 


fit = fitdist(rmmissing(VARTempMatrix(:,app.StepladderCurrencyDropDown.Value)),"tLocationScale");
Qvec1 = [norminv(VarESQvec) tinv(VarESQvec,fit.nu) ];

%A SECONDA DELLA MODALITà SELEZIONATA, ELABORA DEGLI SCENARI DEL TASSO DI
%CAMBIO IN DIMINUZIONE


if SummaryTable.Delta(end) > 0 && SummaryTable.Price(end) > 0
    VAR_Scenerios = exp(sq.*Qvec1(:,app.VaRandESMethodDropDown.Value)).*app.Prices{app.StepladderCurrencyDropDown.Value}(end);
    
    if app.VaRandESMethodDropDown.Value == 1
    
        ES_Scenarios = exp(-VarESQvec.^-1 .* normpdf(norminv(VarESQvec))*sq).*app.Prices{app.StepladderCurrencyDropDown.Value}(end);
    else
        tStud_PDF = tpdf(tinv(VarESQvec,fit.nu),fit.nu);
        shock = exp(-sq.*((fit.nu+Qvec1(:,2).^2)./(fit.nu-1)).*(tStud_PDF./(VarESQvec)));
        ES_Scenarios = shock.*app.Prices{app.StepladderCurrencyDropDown.Value}(end);
    end
end
if SummaryTable.Delta(end) < 0 && SummaryTable.Price(end) > 0
    VAR_Scenerios = exp(-sq.*Qvec1(:,app.VaRandESMethodDropDown.Value)).*app.Prices{app.StepladderCurrencyDropDown.Value}(end);
    
    if app.VaRandESMethodDropDown.Value == 1
    
        ES_Scenarios = exp(VarESQvec.^-1 .* normpdf(norminv(VarESQvec))*sq).*app.Prices{app.StepladderCurrencyDropDown.Value}(end);
    else
        tStud_PDF = tpdf(tinv(VarESQvec,fit.nu),fit.nu);
        shock = exp(sq.*((fit.nu+Qvec1(:,2).^2)./(fit.nu-1)).*(tStud_PDF./(VarESQvec)));
        ES_Scenarios = shock.*app.Prices{app.StepladderCurrencyDropDown.Value}(end);
    end
end
if SummaryTable.Delta(end) > 0 && SummaryTable.Price(end) < 0
    
            VAR_Scenerios = exp(sq.*Qvec1(:,app.VaRandESMethodDropDown.Value)).*app.Prices{app.StepladderCurrencyDropDown.Value}(end);
    
    if app.VaRandESMethodDropDown.Value == 1
    
        ES_Scenarios = exp(-VarESQvec.^-1 .* normpdf(norminv(VarESQvec))*sq).*app.Prices{app.StepladderCurrencyDropDown.Value}(end);
    else
        tStud_PDF = tpdf(tinv(VarESQvec,fit.nu),fit.nu);
        shock = exp(-sq.*((fit.nu+Qvec1(:,2).^2)./(fit.nu-1)).*(tStud_PDF./(VarESQvec)));
        ES_Scenarios = shock.*app.Prices{app.StepladderCurrencyDropDown.Value}(end);
    end
    
   
    end

if SummaryTable.Delta(end) < 0 && SummaryTable.Price(end) < 0
   VAR_Scenerios = exp(-sq.*Qvec1(:,app.VaRandESMethodDropDown.Value)).*app.Prices{app.StepladderCurrencyDropDown.Value}(end);
    
    if app.VaRandESMethodDropDown.Value == 1
    
        ES_Scenarios = exp(VarESQvec.^-1 .* normpdf(norminv(VarESQvec))*sq).*app.Prices{app.StepladderCurrencyDropDown.Value}(end);
    else
        tStud_PDF = tpdf(tinv(VarESQvec,fit.nu),fit.nu);
        shock = exp(sq.*((fit.nu+Qvec1(:,2).^2)./(fit.nu-1)).*(tStud_PDF./(VarESQvec)));
        ES_Scenarios = shock.*app.Prices{app.StepladderCurrencyDropDown.Value}(end);
    end
end


ES_Scenarios_SpotPrice = [ES_Scenarios ; app.Prices{app.StepladderCurrencyDropDown.Value}(end) ];
VAR_Scenarios_SpotPrice = [VAR_Scenerios ; app.Prices{app.StepladderCurrencyDropDown.Value}(end)];

for i = 1:length(VAR_Scenarios_SpotPrice)
    for k= 1:length(TempPricer) 
        TempPricer(k).SpotPrice = VAR_Scenarios_SpotPrice(i);
    end
    [~, ~, PortSensVAR, ~] = pricePortfolio(finportfolio(TempInstruments,TempPricer,TempQuantities));
    SummaryTableVAR(i,:) = PortSensVAR;
    
end


for i = 1:length(ES_Scenarios_SpotPrice)
    for k= 1:length(TempPricer)
        TempPricer(k).SpotPrice = ES_Scenarios_SpotPrice(i);
    end
    [~, ~, PortSensES, ~] = pricePortfolio(finportfolio(TempInstruments,TempPricer,TempQuantities));
    SummaryTableES(i,:) = PortSensES;
    
end

ES(1:3,F) = SummaryTableES{end,1}-SummaryTableES{1:end-1,1};
VaR(1:3,F) = SummaryTableVAR{end,1}-SummaryTableVAR{1:end-1,1};
end
plot(app.UIAxes8,TestWindow,VaR(3,:),TestWindow,VaR(2,:),TestWindow,VaR(1,:))
hold(app.UIAxes8,"on")
legend(app.UIAxes8,{'VaR 95' 'VaR 99' 'VaR 99.9'})

if app.VaRLimitMlnEditField.Value == 0
    hold(app.UIAxes8,"off")
else
    plot(app.UIAxes8,TestWindow,repmat(app.VaRLimitMlnEditField.Value,1, size(VaR,2)))
    hold(app.UIAxes8,"off")
end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%ù
%VAR LIMITS EXCEPTIONS

Excep_Ret = app.RetTable(app.RetTable.Date > TestWindow(1),[1 app.StepladderCurrencyDropDown.Value]);
Execp_Ret_Matrix = table2array(Excep_Ret(:,2:end));

                    index = Execp_Ret_Matrix == 0;
                [row,col] = find(index);
            
                if nnz(index) >0
                    for i = 1:height(row)
                        Execp_Ret_Matrix(row(i),col(i)) = nan;
                    end
                end

DateIdx = app.RetTable.Date > TempWindow(1);         
TestPrices = cumprod([app.PriceMatrix{app.StartDateDatePicker_4.Value,app.StepladderCurrencyDropDown.Value}; exp(Except_Ret)]);

for i = 1:length(TestPrices)
    for k = 1:length(TempPricer)
        TempPricer(k).SpotPrice = TestPrice(i)
    end
    PortPrice = pricePortfolio(TempInstruments,TempPricer,TempQuantities);
    PortPriceArray(i,1) = PortPrice
end


ZoomInd   = (TestWindow >= app.StartDateDatePicker_4.Value) & (TestWindow <= app.EndDateDatePicker_3.Value);
VaRData   = [-VaR(3,ZoomInd); -VaR(2,ZoomInd); -VaR(1,ZoomInd)];
VaRFormat = {'-','--','-.'};
D = TestWindow(ZoomInd)';
R = PortPrice(ZoomInd');
N99 = VaR(1,ZoomInd)';
N999 = VaR(2,ZoomInd)';
N95 = VaR(3,ZoomInd)';
IndN99    = (R < -N99)';
IndN999   = (R < -N999)';
IndN95 = (R < -N95)';

bar(app.UIAxes9,D,R,0.5,'FaceColor',[0.7 0.7 0.7]);
hold(app.UIAxes9,"on")
for i = 1 : size(VaRData,1)
    stairs(app.UIAxes9,D-0.5,VaRData(i,:),VaRFormat{i});
end
ylabel(app.UIAxes9,'VaR')
xlabel(app.UIAxes9,'Date')
legend(app.UIAxes9,{'Returns','Normal','Historical','EWMA'},'Location','Best','AutoUpdate','Off')
title(app.UIAxes9,'95% VaR violations for different models')


%plot(app.UIAxes9,D(IndN95),-N(IndN95),'o',D(IndHS95),-H(IndHS95),'o',...
%   D(IndEWMA95),-E(IndEWMA95),'o','MarkerSize',8,'LineWidth',1.5)
%xlim([D(1)-1, D(end)+1])
%hold off;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Risk Management Dashboard for Option Trading Desk v1.0 - The Black Swan Team';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PortfolioSelectionTab = uitab(app.TabGroup);
            app.PortfolioSelectionTab.Title = 'Portfolio Selection';
            app.AddOptiontoPortfolioPanel = uipanel(app.PortfolioSelectionTab);
            app.AddOptiontoPortfolioPanel.Title = 'Add Option to Portfolio';
            app.SetDateandRatesButton = uibutton(app.AddOptiontoPortfolioPanel, 'push');
            app.SetDateandRatesButton.ButtonPushedFcn = createCallbackFcn(app, @SetDateandRatesButtonPushed, true);
            app.SetDateandRatesButton.Text = 'Set Date and Rates';
            app.AddtoPortfolioButton = uibutton(app.AddOptiontoPortfolioPanel, 'push');
            app.AddtoPortfolioButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoPortfolioButtonPushed, true);
            app.AddtoPortfolioButton.Enable = 'off';
            app.AddtoPortfolioButton.Text = 'Add to Portfolio';
            app.DeletePortfolioButton = uibutton(app.AddOptiontoPortfolioPanel, 'push');
            app.DeletePortfolioButton.ButtonPushedFcn = createCallbackFcn(app, @DeletePortfolioButtonPushed, true);
            app.DeletePortfolioButton.Text = 'Delete Portfolio';
            app.OptionStyleButtonGroup = uibuttongroup(app.AddOptiontoPortfolioPanel);
            app.OptionStyleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionStyleButtonGroupSelectionChanged, true);
            app.OptionStyleButtonGroup.Title = 'Option Style';
            app.EuropeanButton = uiradiobutton(app.OptionStyleButtonGroup);
            app.EuropeanButton.Text = 'European';
            app.EuropeanButton.Value = true;
            app.AmericanButton = uiradiobutton(app.OptionStyleButtonGroup);
            app.AmericanButton.Text = 'American';
            app.PositionButtonGroup = uibuttongroup(app.AddOptiontoPortfolioPanel);
            app.PositionButtonGroup.Title = 'Position';
            app.LongButton = uiradiobutton(app.PositionButtonGroup);
            app.LongButton.Text = 'Long';
            app.LongButton.Value = true;
            app.ShortButton = uiradiobutton(app.PositionButtonGroup);
            app.ShortButton.Text = {'Short'; ''};
            app.OptionTypeButtonGroup = uibuttongroup(app.AddOptiontoPortfolioPanel);
            app.OptionTypeButtonGroup.Title = 'Option Type';
            app.CallButton = uiradiobutton(app.OptionTypeButtonGroup);
            app.CallButton.Text = 'Call';
            app.CallButton.Value = true;
            app.PutButton = uiradiobutton(app.OptionTypeButtonGroup);
            app.PutButton.Text = 'Put';
            app.InstrumentTypeDropDown = uidropdown(app.AddOptiontoPortfolioPanel);
            app.InstrumentTypeDropDown.Items = {'Vanilla', 'Binary', 'Barrier', 'Lookback'};
            app.InstrumentTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @InstrumentTypeDropDownValueChanged, true);
            app.InstrumentTypeDropDown.Value = 'Vanilla';
            app.InstrumentTypeDropDownLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.InstrumentTypeDropDownLabel.Text = 'Instrument Type';
            app.StrikeEditField = uieditfield(app.AddOptiontoPortfolioPanel, 'numeric');
            app.StrikeEditField.Limits = [0 Inf];
            app.StrikeEditField.Value = 90;
            app.StrikeEditFieldLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.StrikeEditFieldLabel.Text = 'Strike';
            app.UnderlyingPriceEditFieldLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.UnderlyingPriceEditFieldLabel.Text = 'Underlying Price';
            app.UnderlyingPriceEditField = uieditfield(app.AddOptiontoPortfolioPanel, 'numeric');
            app.UnderlyingPriceEditField.Value = 90;
            app.VolatilityEditFieldLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.VolatilityEditFieldLabel.Text = 'Volatility';
            app.VolatilityEditField = uieditfield(app.AddOptiontoPortfolioPanel, 'numeric');
            app.VolatilityEditField.Value = 0.12;
            app.OptionMaturityDatePickerLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.OptionMaturityDatePickerLabel.Text = 'Option Maturity';
            app.OptionMaturityDatePicker = uidatepicker(app.AddOptiontoPortfolioPanel);
            app.OptionMaturityDatePicker.Value = datetime([2021 3 3]);
            app.YieldEditField = uieditfield(app.AddOptiontoPortfolioPanel, 'numeric');
            app.YieldLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.YieldLabel.Text = 'Yield (%)';
            app.BarrierPanel = uipanel(app.AddOptiontoPortfolioPanel);
            app.BarrierPanel.Title = 'Barrier Panel';
            app.GridLayout = uigridlayout(app.BarrierPanel);
            app.BarrierTypeDropDown = uidropdown(app.GridLayout);
            app.BarrierTypeDropDown.Items = {'Up Knock-In', 'Up Knock-Out', 'Down Knock-In', 'Down Knock-Out'};
            app.BarrierTypeDropDown.ItemsData = {'UI', 'UO', 'DI', 'DO'};
            app.BarrierTypeDropDown.Value = 'UI';
            app.BarrierTypeLabel = uilabel(app.GridLayout);
            app.BarrierTypeLabel.Text = 'Barrier Type ';
            app.BarrierValueLabel = uilabel(app.GridLayout);
            app.BarrierValueLabel.Text = 'Barrier Value ';
            app.BarrierValueEditField = uieditfield(app.GridLayout, 'numeric');
            app.BarrierValueEditField.Limits = [0 Inf];
            app.BinaryPanel = uipanel(app.AddOptiontoPortfolioPanel);
            app.BinaryPanel.Title = 'Binary Panel';
            app.GridLayout2 = uigridlayout(app.BinaryPanel);
            app.BinaryValueEditField = uieditfield(app.GridLayout2, 'numeric');
            app.BinaryValueEditField.Limits = [0 Inf];
            app.BinaryValueEditFieldLabel = uilabel(app.GridLayout2);
            app.BinaryValueEditFieldLabel.Text = 'Binary Value';
            app.SimulationTrialsEditFieldLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.SimulationTrialsEditFieldLabel.Text = 'Simulation Trials';
            app.SimulationTrialsEditField = uieditfield(app.AddOptiontoPortfolioPanel, 'numeric');
            app.SimulationTrialsEditField.Enable = 'off';
            app.SimulationTrialsEditField.Value = 1000;
            app.QuantitySpinner = uispinner(app.AddOptiontoPortfolioPanel);
            app.QuantitySpinner.Limits = [0 Inf];
            app.QuantitySpinner.Value = 1;
            app.QuantitySpinnerLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.QuantitySpinnerLabel.Text = 'Quantity';
            app.BaseCurrencyDropDownLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.BaseCurrencyDropDownLabel.Text = 'Base Currency';
            app.BaseCurrencyDropDown = uidropdown(app.AddOptiontoPortfolioPanel);
            app.BaseCurrencyDropDown.Items = {'USD/AUD', 'EUR/USD', 'USD/GBP', 'AUD/GBP', 'EUR/GBP', 'AUD/EUR'};
            app.BaseCurrencyDropDown.ValueChangedFcn = createCallbackFcn(app, @BaseCurrencyDropDownValueChanged, true);
            app.BaseCurrencyDropDown.Value = 'USD/AUD';
            app.PortfolioCompositionPanel = uipanel(app.PortfolioSelectionTab);
            app.PortfolioCompositionPanel.Title = 'Portfolio Composition';
            app.UIAxes5 = uiaxes(app.PortfolioCompositionPanel);
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes4 = uiaxes(app.PortfolioCompositionPanel);
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes2 = uiaxes(app.PortfolioCompositionPanel);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.PortfolioCompositionPanel);
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UITable3 = uitable(app.PortfolioSelectionTab);
            app.UITable3.ColumnName = '';
            app.UITable3.RowName = {};
            app.UITable3.ColumnSortable = [true true true true true true true true true true true true];
            app.UITable4 = uitable(app.PortfolioSelectionTab);
            app.UITable4.ColumnName = '';
            app.UITable4.RowName = {};
            app.UITable2 = uitable(app.PortfolioSelectionTab);
            app.UITable2.ColumnName = '';
            app.UITable2.RowName = {};
            app.UITable2.ColumnSortable = [true true true true true true true true true true true];
            app.LONGLabel = uilabel(app.PortfolioSelectionTab);
            app.LONGLabel.Text = 'LONG';
            app.SHORTLabel = uilabel(app.PortfolioSelectionTab);
            app.SHORTLabel.Text = 'SHORT';
            app.TOTLabel = uilabel(app.PortfolioSelectionTab);
            app.TOTLabel.Text = 'TOT';
            app.TimeSeriesAnalysisTab = uitab(app.TabGroup);
            app.TimeSeriesAnalysisTab.Title = 'Time Series Analysis';
            app.TimeSeriesChartPanel = uipanel(app.TimeSeriesAnalysisTab);
            app.TimeSeriesChartPanel.Title = 'Time Series Chart';
            app.UIAxes6 = uiaxes(app.TimeSeriesChartPanel);
            xlabel(app.UIAxes6, 'Date')
            ylabel(app.UIAxes6, 'Exchange Rate')
            zlabel(app.UIAxes6, 'Z')
            app.TimeSeriesChartControlPanel = uipanel(app.TimeSeriesAnalysisTab);
            app.TimeSeriesChartControlPanel.Title = 'Time Series Chart Control Panel';
            app.StartDateDatePickerLabel = uilabel(app.TimeSeriesChartControlPanel);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDateDatePicker = uidatepicker(app.TimeSeriesChartControlPanel);
            app.StartDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @StartDateDatePickerValueChanged, true);
            app.StartDateDatePicker.Value = datetime([1995 3 2]);
            app.EndDateDatePickerLabel = uilabel(app.TimeSeriesChartControlPanel);
            app.EndDateDatePickerLabel.Text = 'End Date';
            app.EndDateDatePicker = uidatepicker(app.TimeSeriesChartControlPanel);
            app.EndDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @EndDateDatePickerValueChanged, true);
            app.EndDateDatePicker.Value = datetime([2021 3 4]);
            app.Series1DropDownLabel = uilabel(app.TimeSeriesChartControlPanel);
            app.Series1DropDownLabel.Text = 'Series 1';
            app.Series1DropDown = uidropdown(app.TimeSeriesChartControlPanel);
            app.Series1DropDown.Items = {'USD/AUD', 'EUR/USD', 'USD/GBP', 'AUD/GBP', 'EUR/GBP', 'AUD/EUR'};
            app.Series1DropDown.ValueChangedFcn = createCallbackFcn(app, @Series1DropDownValueChanged, true);
            app.Series1DropDown.Value = 'USD/AUD';
            app.Series2DropDownLabel = uilabel(app.TimeSeriesChartControlPanel);
            app.Series2DropDownLabel.Text = 'Series 2';
            app.Series2DropDown = uidropdown(app.TimeSeriesChartControlPanel);
            app.Series2DropDown.Items = {'No plot', 'USD/AUD', 'EUR/USD', 'USD/GBP', 'AUD/GBP', 'EUR/GBP', 'AUD/EUR'};
            app.Series2DropDown.ValueChangedFcn = createCallbackFcn(app, @Series2DropDownValueChanged, true);
            app.Series2DropDown.Value = 'EUR/USD';
            app.LogReturnsDistributionFitterPanel = uipanel(app.TimeSeriesAnalysisTab);
            app.LogReturnsDistributionFitterPanel.Title = 'Log-Returns Distribution Fitter';
            app.CurrencyDropDownLabel = uilabel(app.LogReturnsDistributionFitterPanel);
            app.CurrencyDropDownLabel.Text = 'Currency';
            app.CurrencyDropDown = uidropdown(app.LogReturnsDistributionFitterPanel);
            app.CurrencyDropDown.Items = {'USD/AUD', 'EUR/USD', 'USD/GBP', 'AUD/GBP', 'EUR/GBP', 'AUD/EUR'};
            app.CurrencyDropDown.ItemsData = [1 2 3 4 5 6];
            app.CurrencyDropDown.ValueChangedFcn = createCallbackFcn(app, @CurrencyDropDownValueChanged, true);
            app.CurrencyDropDown.Value = 1;
            app.StartDateDatePicker_3Label = uilabel(app.LogReturnsDistributionFitterPanel);
            app.StartDateDatePicker_3Label.Text = 'Start Date';
            app.StartDateDatePicker_3 = uidatepicker(app.LogReturnsDistributionFitterPanel);
            app.StartDateDatePicker_3.ValueChangedFcn = createCallbackFcn(app, @StartDateDatePicker_3ValueChanged, true);
            app.OverlayDistributionCheckBox = uicheckbox(app.LogReturnsDistributionFitterPanel);
            app.OverlayDistributionCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlayDistributionCheckBoxValueChanged, true);
            app.OverlayDistributionCheckBox.Text = 'Overlay Distribution';
            app.DistributionTypeDropDownLabel = uilabel(app.LogReturnsDistributionFitterPanel);
            app.DistributionTypeDropDownLabel.Text = 'Distribution Type';
            app.DistributionTypeDropDown = uidropdown(app.LogReturnsDistributionFitterPanel);
            app.DistributionTypeDropDown.Items = {'Normal', 'Student-t'};
            app.DistributionTypeDropDown.ItemsData = [1 2];
            app.DistributionTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DistributionTypeDropDownValueChanged, true);
            app.DistributionTypeDropDown.Enable = 'off';
            app.DistributionTypeDropDown.Value = 1;
            app.TextArea = uitextarea(app.LogReturnsDistributionFitterPanel);
            app.UIAxes7 = uiaxes(app.LogReturnsDistributionFitterPanel);
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UITable5 = uitable(app.TimeSeriesAnalysisTab);
            app.UITable5.ColumnName = '';
            app.UITable5.RowName = {};
            app.UITable5.ColumnSortable = [true true true true true true true true true true true true true true true true];
            app.CorrelationMapPanel = uipanel(app.TimeSeriesAnalysisTab);
            app.CorrelationMapPanel.Title = 'Correlation Map';
            app.CorrelationMapandSummaryStatisticsControlPanel = uipanel(app.TimeSeriesAnalysisTab);
            app.CorrelationMapandSummaryStatisticsControlPanel.Title = 'Correlation Map and Summary Statistics Control Panel';
            app.StartDateDatePicker_2Label = uilabel(app.CorrelationMapandSummaryStatisticsControlPanel);
            app.StartDateDatePicker_2Label.Text = 'Start Date';
            app.StartDateDatePicker_2 = uidatepicker(app.CorrelationMapandSummaryStatisticsControlPanel);
            app.StartDateDatePicker_2.ValueChangedFcn = createCallbackFcn(app, @StartDateDatePicker_2ValueChanged, true);
            app.StartDateDatePicker_2.Value = datetime([1995 3 2]);
            app.EndDateDatePicker_2Label = uilabel(app.CorrelationMapandSummaryStatisticsControlPanel);
            app.EndDateDatePicker_2Label.Text = 'End Date';
            app.EndDateDatePicker_2 = uidatepicker(app.CorrelationMapandSummaryStatisticsControlPanel);
            app.EndDateDatePicker_2.ValueChangedFcn = createCallbackFcn(app, @EndDateDatePicker_2ValueChanged, true);
            app.EndDateDatePicker_2.Value = datetime([2021 3 4]);
            app.BackgroundColorButton = uibutton(app.CorrelationMapandSummaryStatisticsControlPanel, 'state');
            app.BackgroundColorButton.ValueChangedFcn = createCallbackFcn(app, @BackgroundColorButtonValueChanged, true);
            app.BackgroundColorButton.Text = 'Background Color';
            app.BackgroundColorButton.Value = true;
            app.VaRCalculationandBacktestingTab = uitab(app.TabGroup);
            app.VaRCalculationandBacktestingTab.Title = 'VaR Calculation and Backtesting';
            app.UITable6 = uitable(app.VaRCalculationandBacktestingTab);
            app.UITable6.ColumnName = '';
            app.UITable6.RowName = {};
            app.StepladderCurrencyDropDownLabel = uilabel(app.VaRCalculationandBacktestingTab);
            app.StepladderCurrencyDropDownLabel.Text = 'Stepladder Currency';
            app.StepladderCurrencyDropDown = uidropdown(app.VaRCalculationandBacktestingTab);
            app.StepladderCurrencyDropDown.Items = {'USD/AUD', 'EUR/USD', 'USD/GBP', 'AUD/GBP', 'EUR/GBP', 'AUD/EUR'};
            app.StepladderCurrencyDropDown.ItemsData = [1 2 3 4 5 6];
            app.StepladderCurrencyDropDown.Value = 1;
            app.Button = uibutton(app.VaRCalculationandBacktestingTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.SamplingPeriodDropDownLabel = uilabel(app.VaRCalculationandBacktestingTab);
            app.SamplingPeriodDropDownLabel.Text = 'Sampling Period';
            app.SamplingPeriodDropDown = uidropdown(app.VaRCalculationandBacktestingTab);
            app.SamplingPeriodDropDown.Items = {'6 Months', '9 Months', '1 Year', '2 Years', '3 Years', '5 Years'};
            app.SamplingPeriodDropDown.ItemsData = [6 9 12 24 36 60];
            app.SamplingPeriodDropDown.Value = 6;
            app.RiskHorizonEditFieldLabel = uilabel(app.VaRCalculationandBacktestingTab);
            app.RiskHorizonEditFieldLabel.Text = 'Risk Horizon';
            app.RiskHorizonEditField = uieditfield(app.VaRCalculationandBacktestingTab, 'numeric');
            app.RiskHorizonEditField.Value = 1;
            app.TextArea_2Label = uilabel(app.VaRCalculationandBacktestingTab);
            app.TextArea_2Label.Text = 'Text Area';
            app.TextArea_2 = uitextarea(app.VaRCalculationandBacktestingTab);
            app.UITable7 = uitable(app.VaRCalculationandBacktestingTab);
            app.UITable7.ColumnName = '';
            app.UITable7.RowName = {};
            app.UITable8 = uitable(app.VaRCalculationandBacktestingTab);
            app.UITable8.ColumnName = '';
            app.UITable8.RowName = {};
            app.VaRandESMethodDropDownLabel = uilabel(app.VaRCalculationandBacktestingTab);
            app.VaRandESMethodDropDownLabel.Text = 'VaR and ES Method';
            app.VaRandESMethodDropDown = uidropdown(app.VaRCalculationandBacktestingTab);
            app.VaRandESMethodDropDown.Items = {'Normal Distribution', 'Student''s t Distribution'};
            app.VaRandESMethodDropDown.ItemsData = [1 2];
            app.VaRandESMethodDropDown.Value = 1;
            app.VaRBacktestingHorizonDatePickerLabel = uilabel(app.VaRCalculationandBacktestingTab);
            app.VaRBacktestingHorizonDatePickerLabel.Text = 'VaR Backtesting Horizon';
            app.VaRBacktestingHorizonDatePicker = uidatepicker(app.VaRCalculationandBacktestingTab);
            app.VaRBacktestingHorizonDatePicker.Value = datetime([2020 3 11]);
            app.VaRLimitMlnEditFieldLabel = uilabel(app.VaRCalculationandBacktestingTab);
            app.VaRLimitMlnEditFieldLabel.Text = 'VaR Limit (Mln)';
            app.VaRLimitMlnEditField = uieditfield(app.VaRCalculationandBacktestingTab, 'numeric');
            app.SamplingRollingWindowDropDownLabel = uilabel(app.VaRCalculationandBacktestingTab);
            app.SamplingRollingWindowDropDownLabel.Text = 'Sampling Rolling Window';
            app.SamplingRollingWindowDropDown = uidropdown(app.VaRCalculationandBacktestingTab);
            app.SamplingRollingWindowDropDown.Items = {'6 Months', '9 Months', '1 Year', '2 Years', '3 Years', '5 Years'};
            app.SamplingRollingWindowDropDown.ItemsData = [6 9 12 24 36 60];
            app.SamplingRollingWindowDropDown.Value = 6;
            app.StartDateDatePicker_4Label = uilabel(app.VaRCalculationandBacktestingTab);
            app.StartDateDatePicker_4Label.Text = 'Start Date';
            app.StartDateDatePicker_4 = uidatepicker(app.VaRCalculationandBacktestingTab);
            app.EndDateDatePicker_3Label = uilabel(app.VaRCalculationandBacktestingTab);
            app.EndDateDatePicker_3Label.Text = 'End Date';
            app.EndDateDatePicker_3 = uidatepicker(app.VaRCalculationandBacktestingTab);
            app.UIAxes8 = uiaxes(app.VaRCalculationandBacktestingTab);
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes9 = uiaxes(app.VaRCalculationandBacktestingTab);
            title(app.UIAxes9, 'Title')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            zlabel(app.UIAxes9, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PRMIA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end